classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        piButton          matlab.ui.control.Button
        xButton_2         matlab.ui.control.Button
        expButton         matlab.ui.control.Button
        aCothButton       matlab.ui.control.Button
        aTanhButton       matlab.ui.control.Button
        aCoshButton       matlab.ui.control.Button
        aSinhButton       matlab.ui.control.Button
        aCotButton        matlab.ui.control.Button
        aSinButton        matlab.ui.control.Button
        aTanButton        matlab.ui.control.Button
        aCosButton        matlab.ui.control.Button
        CotButton         matlab.ui.control.Button
        TanButton         matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        Button_24         matlab.ui.control.Button
        xButton           matlab.ui.control.Button
        ButtonGroup       matlab.ui.container.ButtonGroup
        RadianButton      matlab.ui.control.RadioButton
        DegreeButton      matlab.ui.control.RadioButton
        iButton           matlab.ui.control.Button
        Button_22         matlab.ui.control.Button
        PodstawowyButton  matlab.ui.control.Button
        CosButton         matlab.ui.control.Button
        SinButton         matlab.ui.control.Button
        exp1Button        matlab.ui.control.Button
        Button_21         matlab.ui.control.Button
        x2Button          matlab.ui.control.Button
        Button_19         matlab.ui.control.Button
        Button_18         matlab.ui.control.Button
        Button_16         matlab.ui.control.Button
        Button_17         matlab.ui.control.Button
        Button_15         matlab.ui.control.Button
        Button_14         matlab.ui.control.Button
        Button_13         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        CLEARButton       matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        TextAreaLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_12, Button_13, Button_15, 
        % ...and 17 other components
        function PRZYCISK(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: Button_14
        function WYNIK(app, event)
            try
                app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                    num2str(eval(app.TextArea.Value{1}))];
            catch
                app.TextArea.Value='Error!';
            end
        end

        % Button pushed function: SinButton
        function SIN(app, event)
             inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(sind(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(sin(inputValue));
            end
        end

        % Button pushed function: CosButton
        function COS(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(cosd(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(cos(inputValue));
            end                                 
        end

        % Button pushed function: TanButton
        function TAN(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(tand(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(tan(inputValue));
            end
        end

        % Button pushed function: CotButton
        function COT(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(cotd(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(cot(inputValue));
            end                     
        end

        % Button pushed function: aSinButton
        function ASIN(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(asind(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(asin(inputValue));
            end
        end

        % Button pushed function: aCosButton
        function ACOS(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(acosd(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(acos(inputValue));
            end
        end

        % Button pushed function: aTanButton
        function ATAN(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(atand(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(atan(inputValue));
            end
        end

        % Button pushed function: aCotButton
        function ACOT(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            
            if(app.DegreeButton.Value)
                app.TextArea.Value = num2str(acotd(inputValue));
            else(app.RadianButton.Value)
                app.TextArea.Value = num2str(acot(inputValue));
            end
        end

        % Button pushed function: aSinhButton
        function ASINH(app, event)
           inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(asinh(inputValue));
        end

        % Button pushed function: aCoshButton
        function ACOSH(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(acosh(inputValue));  
        end

        % Button pushed function: aTanhButton
        function ATANH(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(atanh(inputValue));
        end

        % Button pushed function: aCothButton
        function ACOTH(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(acoth(inputValue));
        end

        % Button pushed function: expButton
        function EXPONENS(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(exp(inputValue));
        end

        % Button pushed function: Button_21
        function PIERWIASTEK(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(inputValue.^(1/2));        
        end

        % Button pushed function: x2Button
        function DOKWADRATU(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(inputValue.^2);
        end

        % Button pushed function: xButton
        function ODWRTONOSC(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(inputValue.^(-1));   
        end

        % Button pushed function: xButton_2
        function MODUL(app, event)
            inputValue = str2num(app.TextArea.Value{1});
            app.TextArea.Value = num2str(abs((inputValue)));
        end

        % Button pushed function: CLEARButton
        function CLEAR(app, event)
            app.TextArea.Value = "";
            
        end

        % Button pushed function: PodstawowyButton
        function TRYB_NAUKOWY(app, event)
            switch(app.PodstawowyButton.Text)
                case 'Podstawowy'
                    app.PodstawowyButton.Text = 'Naukowy';
                    app.SinButton.Visible = 'off';
                    app.CosButton.Visible = 'off';
                    app.TanButton.Visible = 'off';
                    app.CotButton.Visible = 'off';
                    app.aSinButton.Visible = 'off';
                    app.aCosButton.Visible = 'off';
                    app.aTanButton.Visible = 'off';
                    app.aCotButton.Visible = 'off';
                    app.aSinhButton.Visible = 'off';
                    app.aCoshButton.Visible = 'off';
                    app.aTanhButton.Visible = 'off';
                    app.aCothButton.Visible = 'off';
                    app.TanButton.Visible = 'off';                    
                    app.ButtonGroup.Visible = 'off';
                    
                case 'Naukowy'
                    app.PodstawowyButton.Text = 'Podstawowy';
                    app.SinButton.Visible = 'on';
                    app.CosButton.Visible = 'on';
                    app.TanButton.Visible = 'on';
                    app.CotButton.Visible = 'on';
                    app.aSinButton.Visible = 'on';
                    app.aCosButton.Visible = 'on';
                    app.aTanButton.Visible = 'on';
                    app.aCotButton.Visible = 'on';
                    app.aSinhButton.Visible = 'on';
                    app.aCoshButton.Visible = 'on';
                    app.aTanhButton.Visible = 'on';
                    app.aCothButton.Visible = 'on';
                    app.TanButton.Visible = 'on';
                    app.ButtonGroup.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_9.Text = '9';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEAR, true);
            app.CLEARButton.Text = 'CLEAR';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_12.Text = '+';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_13.Text = '-';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @WYNIK, true);
            app.Button_14.Text = '=';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_15.Text = '.';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_17.Text = '*';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_16.Text = '/';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_18.Text = '(';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_19.Text = ')';
            app.x2Button = uibutton(app.UIFigure, 'push');
            app.x2Button.ButtonPushedFcn = createCallbackFcn(app, @DOKWADRATU, true);
            app.x2Button.Text = 'x^2';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @PIERWIASTEK, true);
            app.Button_21.Text = '√';
            app.exp1Button = uibutton(app.UIFigure, 'push');
            app.exp1Button.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.exp1Button.Text = 'exp(1)';
            app.SinButton = uibutton(app.UIFigure, 'push');
            app.SinButton.ButtonPushedFcn = createCallbackFcn(app, @SIN, true);
            app.SinButton.Text = 'Sin';
            app.CosButton = uibutton(app.UIFigure, 'push');
            app.CosButton.ButtonPushedFcn = createCallbackFcn(app, @COS, true);
            app.CosButton.Text = 'Cos';
            app.PodstawowyButton = uibutton(app.UIFigure, 'push');
            app.PodstawowyButton.ButtonPushedFcn = createCallbackFcn(app, @TRYB_NAUKOWY, true);
            app.PodstawowyButton.Text = 'Podstawowy';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_22.Text = '0';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.iButton.Text = 'i';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.DegreeButton = uiradiobutton(app.ButtonGroup);
            app.DegreeButton.Text = 'Degree';
            app.DegreeButton.Value = true;
            app.RadianButton = uiradiobutton(app.ButtonGroup);
            app.RadianButton.Text = 'Radian';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @ODWRTONOSC, true);
            app.xButton.Text = '1/x';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.Button_24.Text = '.^';
            app.Lamp = uilamp(app.UIFigure);
            app.TanButton = uibutton(app.UIFigure, 'push');
            app.TanButton.ButtonPushedFcn = createCallbackFcn(app, @TAN, true);
            app.TanButton.Text = 'Tan';
            app.CotButton = uibutton(app.UIFigure, 'push');
            app.CotButton.ButtonPushedFcn = createCallbackFcn(app, @COT, true);
            app.CotButton.Text = 'Cot';
            app.aCosButton = uibutton(app.UIFigure, 'push');
            app.aCosButton.ButtonPushedFcn = createCallbackFcn(app, @ACOS, true);
            app.aCosButton.Text = 'aCos';
            app.aTanButton = uibutton(app.UIFigure, 'push');
            app.aTanButton.ButtonPushedFcn = createCallbackFcn(app, @ATAN, true);
            app.aTanButton.Text = 'aTan';
            app.aSinButton = uibutton(app.UIFigure, 'push');
            app.aSinButton.ButtonPushedFcn = createCallbackFcn(app, @ASIN, true);
            app.aSinButton.Text = 'aSin';
            app.aCotButton = uibutton(app.UIFigure, 'push');
            app.aCotButton.ButtonPushedFcn = createCallbackFcn(app, @ACOT, true);
            app.aCotButton.Text = 'aCot';
            app.aSinhButton = uibutton(app.UIFigure, 'push');
            app.aSinhButton.ButtonPushedFcn = createCallbackFcn(app, @ASINH, true);
            app.aSinhButton.Text = 'aSinh';
            app.aCoshButton = uibutton(app.UIFigure, 'push');
            app.aCoshButton.ButtonPushedFcn = createCallbackFcn(app, @ACOSH, true);
            app.aCoshButton.Text = 'acosh';
            app.aTanhButton = uibutton(app.UIFigure, 'push');
            app.aTanhButton.ButtonPushedFcn = createCallbackFcn(app, @ATANH, true);
            app.aTanhButton.Text = 'aTanh';
            app.aCothButton = uibutton(app.UIFigure, 'push');
            app.aCothButton.ButtonPushedFcn = createCallbackFcn(app, @ACOTH, true);
            app.aCothButton.Text = 'acoth';
            app.expButton = uibutton(app.UIFigure, 'push');
            app.expButton.ButtonPushedFcn = createCallbackFcn(app, @EXPONENS, true);
            app.expButton.Text = 'exp()';
            app.xButton_2 = uibutton(app.UIFigure, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @MODUL, true);
            app.xButton_2.Text = '|x|';
            app.piButton = uibutton(app.UIFigure, 'push');
            app.piButton.ButtonPushedFcn = createCallbackFcn(app, @PRZYCISK, true);
            app.piButton.Text = 'pi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end